classdef PBPK_model_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        ControlPanel                   matlab.ui.container.Panel
        OrganSelectionListBoxLabel     matlab.ui.control.Label
        OrganSelectionListBox          matlab.ui.control.ListBox
        ContrastSelectionListBoxLabel  matlab.ui.control.Label
        ContrastSelectionListBox       matlab.ui.control.ListBox
        FlowRateControlSliderLabel     matlab.ui.control.Label
        FlowRateControlSlider          matlab.ui.control.Slider
        SetFlowRateEditFieldLabel      matlab.ui.control.Label
        SetFlowRateEditField           matlab.ui.control.EditField
        VolumeInaccuracyControlinPercentageSliderLabel_1  matlab.ui.control.Label
        VolumeInaccuracyControlinPercentageSlider_1  matlab.ui.control.Slider
        RUNButton                      matlab.ui.control.StateButton
        PAUSEButton                    matlab.ui.control.StateButton
        STATUSBAREditFieldLabel        matlab.ui.control.Label
        STATUSBAREditField             matlab.ui.control.EditField
        STATUSBARDisplayOnlyEditFieldLabel  matlab.ui.control.Label
        ProgressBarLabel               matlab.ui.control.Label
        ProgressBarOutline             matlab.ui.control.EditField
        ProgressBarFillLine            matlab.ui.control.EditField
        VolumeInaccuracyControlinPercentageSliderLabel_2  matlab.ui.control.Label
        VolumeInaccuracyControlinPercentageSlider_2  matlab.ui.control.Slider
        SetVolumeInaccuracy2Label      matlab.ui.control.Label
        SetVolumeInaccuracy2EditField  matlab.ui.control.EditField
        SetVolumeInaccuracy1EditFieldLabel  matlab.ui.control.Label
        SetVolumeInaccuracy1EditField  matlab.ui.control.EditField
        STOP_RESETButton               matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        t_a % Time after the injection has initiated
        Hu % Hounsfield unit for enhancement.
        Flowrate % Controllable flow rate slider
        O_S % Organ selection
        i % Iteration counter for start button press...
        R_b    % Press of reset button
        short_display % smaller length for the display of the status bar
        long_display % smaller length for the display of the status bar
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format bank % Bank style decimal rounding
            %             app.i=1; % Initiating the iteration counter


            %% Status of the button
            app.RUNButton.Enable='on';
            app.RUNButton.Value=0;
            app.RUNButton.BackgroundColor='r';
            
            app.STOP_RESETButton.Visible="off";
            app.STOP_RESETButton.Enable="off";
            app.STOP_RESETButton.Value=0;
            
            app.PAUSEButton.Visible='off';
            app.PAUSEButton.Enable='off';
            app.PAUSEButton.Value=0;
            
            %% Pre-selecting for default selection
            %-----Organ selection
            app.O_S=app.OrganSelectionListBox.Value;
            app.O_S='aortic';
            %------Flow rate selection
            app.FlowRateControlSlider.Value=0;
            app.SetFlowRateEditField.Value=sprintf("%0.2f mls/",0);
            app.Flowrate=0;
            %------Volume inaccuracy
            app.VolumeInaccuracyControlinPercentageSlider_1.Value=0;
            app.SetVolumeInaccuracy1EditField.Value=sprintf("%d%%",0);
            app.VolumeInaccuracyControlinPercentageSlider_2.Value=0;
            app.SetVolumeInaccuracy2EditField.Value=sprintf("%d%%",0);
            
            % Displaying the startup button in the status bar
            app.STATUSBAREditField.Value="Click on the 'RUN' button to execute the code.";
            %Defining two lengths for the status bar
            app.long_display=app.STATUSBAREditField.Position(3);
            app.short_display=app.STATUSBAREditField.Position(3)-229;
            
            %Setting up the display for the progress bar
            % Position "3" is the length increment of the
            % progress bar fill line
            app.ProgressBarLabel.Visible="off";
            app.ProgressBarOutline.Visible="off";
            app.ProgressBarFillLine.Visible="off";
            app.ProgressBarFillLine.Position(3)=0;
            
            %% Message box
            %             uialert(app.UIFigure,"The default selected organ is Aorta" ,"Message","Icon","");
            uiwait(app.UIFigure);
            
        end

        % Value changed function: OrganSelectionListBox
        function OrganSelectionListBoxValueChanged(app, event)
            app.O_S = lower(event.Value);
        end

        % Value changing function: FlowRateControlSlider
        function FlowRateControlSliderValueChanging(app, event)
            %Displaying the  flow rate on the edit field digitally
            app.SetFlowRateEditField.Value=sprintf("%0.2f ml/s",event.Value);
            
        end

        % Value changed function: FlowRateControlSlider
        function FlowRateControlSliderValueChanged(app, event)
            app.Flowrate=event.Value;
            
            if app.Flowrate>=0 && app.STOP_RESETButton.Value==0
                while (app.RUNButton.Value==1 )
                    app.STATUSBAREditField.Value="Executing...";
                    app.STATUSBAREditField.Position(3)=app.short_display;
                    
                    x=(0:10:100);% Progress bar step increments in terms of its position
                    
                    % Initiating the pause button
                    app.STOP_RESETButton.Enable='on';
                    app.PAUSEButton.Enable='on';
                    app.PAUSEButton.Value=0;
                    
                    % Initiating the progress bar
                    app.ProgressBarLabel.Visible="on";
                    app.ProgressBarOutline.Visible="on";
                    app.ProgressBarFillLine.Visible="on";
                    app.ProgressBarLabel.Text=sprintf("%d%%",0);
                    app.ProgressBarFillLine.Position(3)=0;
                    app.ProgressBarFillLine.BackgroundColor='g';
                    
                    switch app.O_S
                        case "aortic"
                            syms Co1(t) Ci Q V
                            ode=diff(Co1,t)==(Q/V)*(Ci-Co1); % Ordinary Differential Equation
                            Co1=dsolve(ode,Co1(0)==0); %ODE solution
                            Co1=subs(Co1,[V,Q],[20,10]); % Substituting volume and flow rate
                            tCi1=[0,0,1,2,3,4,5,6,7,8,9,10,10]; % time range for plot
                            Ci1=5*ones(1,13); % Input concentration for the above defined time
                            Ci1(1)=0;Ci1(end)=0;%Initial and final conditions
                            eqCo1=subs(Co1,{Ci,t},{Ci1(1:end-1),tCi1(1:end-1)});% Finding the output conc.
                            
                            
                            % Updating the progress bar
                            app.ProgressBarFillLine.Position(3)=x(1);
                            app.ProgressBarLabel.Text=sprintf("%d%%",x(1));
                            pause(1)
                            
                            app.ProgressBarFillLine.Position(3)=x(4);
                            app.ProgressBarLabel.Text=sprintf("%d%%",x(4));
                            
                            pause(1.5)
                            
                            
                            %-- Redistributed concentration in the system
                            syms A Ke tCo2
                            % "A" is the known concentration at "0" time which is F*D/Vd, where F is the
                            % bioavailability, D is the Dose, and Vd is the volume of distribution.
                            % "Ke" is the elimination constant.
                            Co2=A*exp(-Ke*t); % Plasma conc. after the bolus is done
                            Ke=0.1386;
                            ACo2=subs(solve(eval(Co2(1))==eqCo1(end-1),A),t,10);% max. at t=10 sec.
                            Co2_sub=subs(eval(Co2),A,ACo2);% Plasma conc. after distribution
                            eqtCo2=solve(Co2_sub==1e-2,t);
                            tCo2=(10:eqtCo2);
                            eqCo2=subs(Co2_sub,t,tCo2(1:end));
                            Co=[eqCo1,eqCo2];
                            tCo=[tCi1(1:end-1),tCo2];
                            pause(1.5)
                            
                            % Updating the progress bar
                            app.ProgressBarFillLine.Position(3)=x(7);
                            app.ProgressBarLabel.Text=sprintf("%d%%",x(7));
                            
                            %Plotting
                            plot(app.UIAxes,tCi1,Ci1,'-r','linewidth',2);% plot input conc. vs. time
                            l1="\color{red}Ci: Input Conc.\bf";
                            %                         legend(app.UIAxes,l1);
                            hold(app.UIAxes,'on')
                            pause(1.5)
                            
                            % Updating the progress bar
                            app.ProgressBarFillLine.Position(3)=x(10);
                            app.ProgressBarLabel.Text=sprintf("%d%%",x(10));
                            
                            %Resuming the plot
                            plot(app.UIAxes,tCo,Co,'--b','linewidth',2);
                            l2=['\color{blue} Co: Output Conc.\bf' newline ...
                                '\color{blue}(bolus geometry + distribution phase)\bf'];
                            legend(app.UIAxes,[l1,l2],'FontSize',14);
                            legend show
                            hold off
                            tend=vpa(tCo2(end)+2);
                            xlim(app.UIAxes,[0 eval(tend)]);
                            ylim(app.UIAxes,[0 eval(max(Co)+0.2)]);
                            
                            % Updating the progress bar
                            app.ProgressBarFillLine.Position(3)=x(11);
                            app.ProgressBarLabel.Text=sprintf("%d%%",x(11));
                            
                        case "hepatic"
                            syms y x
                            y=exp(-app.Flowrate*5);
                            fplot(app.UIAxes,y)
                            
                    end
                    if app.ProgressBarFillLine.Position(3)==100 && ...
                            app.STOP_RESETButton.Enable=="on"
                        
                        app.STATUSBAREditField.Value="Done!";
                        app.STATUSBAREditField.BackgroundColor=[0.3 1 0.65];
                        app.PAUSEButton.Enable="off";
                        app.RUNButton.Enable="on";
                        app.RUNButton.Value=0;
                        app.RUNButton.FontColor='b';
                        app.RUNButton.BackgroundColor='r';
                        app.RUNButton.Text="RE-RUN";
                        
                        %% Disabling the progress bar after execution is complete
                        app.ProgressBarFillLine.Visible="off";
                        app.ProgressBarLabel.Visible="off";
                        app.ProgressBarOutline.Visible="off";
                        
                        app.STOP_RESETButton.Text="RESET";
                        app.STOP_RESETButton.Enable='on';
                        app.PAUSEButton.Enable='off';
                    end
                end
                
            end
            % Disabling the the code until run button is pressed.
            if app.RUNButton.Value==0 &&  app.Flowrate~=0
                app.FlowRateControlSlider.Value=0;
                app.SetFlowRateEditField.Value=sprintf("%0.2f",0);
            end
            
        end

        % Value changed function: RUNButton
        function RUNButtonValueChanged(app, event)
            %Checking if RESET button has been pressed
            
            %Zeroing the slider before RUN button is pressed as a safety
            %precuation
            app.FlowRateControlSlider.Value=0;
            app.SetFlowRateEditField.Value=sprintf("%0.2f",0);
            
            %% clearing all plots before running
            %             cla(app.UIFigure)
            sprintf("this is reset value before press %d", app.STOP_RESETButton.Value)
            
            % Starting/resuming the UI
            uiresume(app.UIFigure);
            
            %Updating the Run Button
            app.RUNButton.Enable='off';
            app.RUNButton.Text="RUN";
            app.RUNButton.BackgroundColor='g';
            app.RUNButton.FontColor=[0.9 1 0.25];
            %             app.RUNButton.Value=0;
            
            %Update the status bar
            app.STATUSBAREditField.BackgroundColor='w';
            app.STATUSBAREditField.Value="Waiting for input...";
            app.STATUSBAREditField.FontColor=[0.7 0 0.9];
            app.STATUSBAREditField.Position(3)=app.short_display;
            
            %Enabling the buttons and updating
            app.PAUSEButton.Visible='on';
            app.PAUSEButton.Enable="on";
            app.STOP_RESETButton.Visible='on';
            app.STOP_RESETButton.Enable="on";
            app.STOP_RESETButton.Text="STOP";% changing the button as stop
            app.STOP_RESETButton.Value=0;
            
            %% Timeout loop
            if app.Flowrate==0 && app.STOP_RESETButton.Value==0
                my_timer=0;
                while true
                    if my_timer<1000
                        app.STATUSBAREditField.BackgroundColor=[0.3 1 0.65];
                        pause(0.1)
                        app.STATUSBAREditField.BackgroundColor='w';
                        pause(0.1)
                    elseif my_timer>=1000
                        %                 0    uialert(app.UIFigure,['\color{blue}The app waited too '...
                        %                         'long for the user input.' ...
                        %                         ' Kindly reset/restart the app'],'Error Message',...
                        %                         'Interpreter','latex')
                        uialert(app.UIFigure,['No user input exist.'...
                            newline 'Kindly restart the app to continue.'],...
                            'Timeout Error','CloseFcn',@(~,~) UIFigureCloseRequest(app));
                        break
                    end
                    my_timer=my_timer+1;% incrementing the timer
                    
                end
            end
            
        end

        % Value changed function: PAUSEButton
        function PAUSEButtonValueChanged(app, event)
            
            %Updating pause button
            app.PAUSEButton.Enable='off';
            app.PAUSEButton.Value=0;
            
            % Updating the GUI
            %-----Setting the RUN Button for pause phase
            app.RUNButton.Enable="on";
            app.RUNButton.Value=0;
            app.RUNButton.FontColor='b';
            app.RUNButton.BackgroundColor='r';
            app.RUNButton.Text="RESUME";
            
            %Updating the status bar
            app.STATUSBAREditField.BackgroundColor='y';
            app.STATUSBAREditField.Value="PAUSING...";
            pause(0.75)
            app.STATUSBAREditField.Value="PAUSED";
            app.PAUSEButton.Enable='off';
            app.STOP_RESETButton.Text="RESET";% changing the button as reset
            %Enabling the reset button once pausing is done.
            app.STOP_RESETButton.Enable="on";
            
            %% Blinking routine
            my_timer_2=0;
            while (app.RUNButton.Value==0)
                if app.STOP_RESETButton.Value==1 % Condition after the reset button is pressed
                    app.STATUSBAREditField.BackgroundColor='w';
                    return
                else
                    app.STATUSBAREditField.BackgroundColor='w';
                    if rem(my_timer_2,2)==0
                        app.STATUSBAREditField.BackgroundColor='y';
                        pause(0.5)
                        app.STATUSBAREditField.BackgroundColor=[0.9 0.9 0.1];
                        pause(0.05)
                        app.STATUSBAREditField.BackgroundColor=[0.85 0.85 0.15];
                        pause(0.05)
                        app.STATUSBAREditField.BackgroundColor=[0.8 0.8 0.2];
                        pause(0.05)
                        app.STATUSBAREditField.BackgroundColor=[0.75 0.75 0.25];
                        pause(0.05)
                        app.STATUSBAREditField.BackgroundColor=[0.7 0.7 0.3];
                    elseif rem(my_timer_2,2)~=0
                        pause(0.05)
                        app.STATUSBAREditField.BackgroundColor='w';
                        pause(0.05)
                    end
                end
                my_timer_2=my_timer_2+1;
            end
            
            %Resetting the press of a reset button
            app.STOP_RESETButton.Value=0;
            
        end

        % Value changed function: STOP_RESETButton
        function STOP_RESETButtonValueChanged(app, event)
            
            % Reset condition is placed under the pause button while loop
            % to override
            if app.STOP_RESETButton.Text=="RESET"
                app.STATUSBAREditField.Value='RESETTING...';
            else
                app.STATUSBAREditField.Value="STOPPING...";
            end
            pause(0.25)
            app.STATUSBAREditField.BackgroundColor=[0.9 0.9 0.9];
            app.STATUSBAREditField.FontColor=[0.8 0 0.3];
            
            app.ProgressBarFillLine.Visible="off";
            app.ProgressBarLabel.Visible="off";
            app.ProgressBarOutline.Visible="off";
            pause(0.25)
            
            
            %% Updating corresponding event changes
            %--------- Update the status bar properties
            if app.STOP_RESETButton.Text=="RESET"
                app.STATUSBAREditField.Value="<^><^><^><^><^> RESET <^><^><^><^><^>";
            else
                app.STATUSBAREditField.Value="<^><^><^><^><^> STOPPED <^><^><^><^><^>";
            end
            app.STATUSBAREditField.FontColor=[0.8 0 0.3];
            app.STATUSBAREditField.BackgroundColor=[0.9 0.9 0.9];
            app.STATUSBAREditField.Position(3)=app.long_display;%
            
            %--------- Resetting the slider controls
            app.FlowRateControlSlider.Value=0;%
            app.Flowrate=0;
            app.VolumeInaccuracyControlinPercentageSlider_1.Value=0;
            app.VolumeInaccuracyControlinPercentageSlider_2.Value=0;
            %---------- Setting the RUN button for re-run
            %             app.RUNButton.BackgroundColor='r';%Color change of run button
            %             app.RUNButton.Text="RE-RUN";
            %             app.RUNButton.Enable="on";% Enable the run button again
            %             app.RUNButton.FontColor='b';
            %             %             app.RUNButton.Value=0;
            pause(0.1);
            app.PAUSEButton.Enable='off';
            app.STOP_RESETButton.Enable='off';%Disabling reset button after press
            
            %-----Setting the RUN Button for pause phase
            app.RUNButton.Enable="on";
            app.RUNButton.Value=0;
            app.RUNButton.FontColor='b';
            app.RUNButton.BackgroundColor='r';
            app.RUNButton.Text="RUN";
            
            cla(app.UIFigure);%Clearing the plot
            uiwait(app.UIFigure)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            cla(app.UIFigure)
            delete(app)
            clc;clear;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1018, 1018};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {682, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Organ_schematic.png';
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = 'ODE.png';
            app.Image3 = uiimage(app.LeftPanel);
            app.Image3.ImageSource = 'CirculationModelSchematic.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Simulated Enhancement')
            xlabel(app.UIAxes, 'Time after the start of the injection')
            ylabel(app.UIAxes, 'Enhancement (Hounsfield Unit)')
            app.UIAxes.XLim = [0 30];
            app.UIAxes.YLim = [0 5];
            app.UIAxes.XTick = [0 5 10 15 20 25 30];
            app.UIAxes.YTick = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.ControlPanel = uipanel(app.RightPanel);
            app.ControlPanel.Title = 'Control Panel';
            app.OrganSelectionListBoxLabel = uilabel(app.ControlPanel);
            app.OrganSelectionListBoxLabel.Text = 'Organ Selection';
            app.OrganSelectionListBox = uilistbox(app.ControlPanel);
            app.OrganSelectionListBox.Items = {'Aortic', 'Hepatic'};
            app.OrganSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @OrganSelectionListBoxValueChanged, true);
            app.OrganSelectionListBox.Value = 'Aortic';
            app.ContrastSelectionListBoxLabel = uilabel(app.ControlPanel);
            app.ContrastSelectionListBoxLabel.Text = 'Contrast Selection';
            app.ContrastSelectionListBox = uilistbox(app.ControlPanel);
            app.ContrastSelectionListBox.Items = {'Iomeron 400', 'Isovue 370', 'Isovue 320'};
            app.ContrastSelectionListBox.Tooltip = {'Select the contrast type'};
            app.ContrastSelectionListBox.Value = 'Iomeron 400';
            app.FlowRateControlSliderLabel = uilabel(app.ControlPanel);
            app.FlowRateControlSliderLabel.Text = 'Flow Rate Control';
            app.FlowRateControlSlider = uislider(app.ControlPanel);
            app.FlowRateControlSlider.Limits = [0 10];
            app.FlowRateControlSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.FlowRateControlSlider.ValueChangedFcn = createCallbackFcn(app, @FlowRateControlSliderValueChanged, true);
            app.FlowRateControlSlider.ValueChangingFcn = createCallbackFcn(app, @FlowRateControlSliderValueChanging, true);
            app.FlowRateControlSlider.Tooltip = {'Use the slider to adjust the CM delivery rate.'; 'Slider works only after clicking the run button.'};
            app.SetFlowRateEditFieldLabel = uilabel(app.ControlPanel);
            app.SetFlowRateEditFieldLabel.Text = 'Set Flow Rate ';
            app.SetFlowRateEditField = uieditfield(app.ControlPanel, 'text');
            app.VolumeInaccuracyControlinPercentageSliderLabel_1 = uilabel(app.ControlPanel);
            app.VolumeInaccuracyControlinPercentageSliderLabel_1.Text = 'Volume Inaccuracy Control 1 (in Percentage)';
            app.VolumeInaccuracyControlinPercentageSlider_1 = uislider(app.ControlPanel);
            app.VolumeInaccuracyControlinPercentageSlider_1.Limits = [0 20];
            app.VolumeInaccuracyControlinPercentageSlider_1.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.VolumeInaccuracyControlinPercentageSlider_1.Tooltip = {'Use the slider to adjust the CM delivery rate.'};
            app.RUNButton = uibutton(app.ControlPanel, 'state');
            app.RUNButton.ValueChangedFcn = createCallbackFcn(app, @RUNButtonValueChanged, true);
            app.RUNButton.Tooltip = {'Click this button to execute the code.'; ''};
            app.RUNButton.Text = 'RUN';
            app.RUNButton.Value = true;
            app.PAUSEButton = uibutton(app.ControlPanel, 'state');
            app.PAUSEButton.ValueChangedFcn = createCallbackFcn(app, @PAUSEButtonValueChanged, true);
            app.PAUSEButton.Tooltip = {'This button is enabled only when the program is running.'};
            app.PAUSEButton.Text = 'PAUSE';
            app.PAUSEButton.Value = true;
            app.STATUSBAREditFieldLabel = uilabel(app.ControlPanel);
            app.STATUSBAREditFieldLabel.Text = 'STATUS BAR';
            app.STATUSBAREditField = uieditfield(app.ControlPanel, 'text');
            app.STATUSBAREditField.Editable = 'off';
            app.STATUSBARDisplayOnlyEditFieldLabel = uilabel(app.ControlPanel);
            app.STATUSBARDisplayOnlyEditFieldLabel.Text = '(Display Only)';
            app.ProgressBarLabel = uilabel(app.ControlPanel);
            app.ProgressBarLabel.Text = '0%';
            app.ProgressBarOutline = uieditfield(app.ControlPanel, 'text');
            app.ProgressBarFillLine = uieditfield(app.ControlPanel, 'text');
            app.VolumeInaccuracyControlinPercentageSliderLabel_2 = uilabel(app.ControlPanel);
            app.VolumeInaccuracyControlinPercentageSliderLabel_2.Text = 'Volume Inaccuracy Control 2 (in Percentage)';
            app.VolumeInaccuracyControlinPercentageSlider_2 = uislider(app.ControlPanel);
            app.VolumeInaccuracyControlinPercentageSlider_2.Limits = [0 20];
            app.VolumeInaccuracyControlinPercentageSlider_2.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.VolumeInaccuracyControlinPercentageSlider_2.Tooltip = {'Use the slider to adjust the CM delivery rate.'};
            app.SetVolumeInaccuracy2Label = uilabel(app.ControlPanel);
            app.SetVolumeInaccuracy2Label.Text = {'Set Volume '; 'Inaccuracy-2 '};
            app.SetVolumeInaccuracy2EditField = uieditfield(app.ControlPanel, 'text');
            app.SetVolumeInaccuracy1EditFieldLabel = uilabel(app.ControlPanel);
            app.SetVolumeInaccuracy1EditFieldLabel.Text = {'Set Volume '; 'Inaccuracy-1'};
            app.SetVolumeInaccuracy1EditField = uieditfield(app.ControlPanel, 'text');
            app.STOP_RESETButton = uibutton(app.ControlPanel, 'state');
            app.STOP_RESETButton.ValueChangedFcn = createCallbackFcn(app, @STOP_RESETButtonValueChanged, true);
            app.STOP_RESETButton.Icon = 'reset_button_icon.jpg';
            app.STOP_RESETButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PBPK_model_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end